classdef PlantCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlantMetricCalculatorUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SingleFileTab                  matlab.ui.container.Tab
        BrowseButton                   matlab.ui.control.Button
        spectrumAxes                   matlab.ui.control.UIAxes
        MeasurementsPanel              matlab.ui.container.Panel
        MicromolessecLabel             matlab.ui.control.Label
        PPFQuantaEditFieldLabel        matlab.ui.control.Label
        PPFQuanta                      matlab.ui.control.NumericEditField
        RadiantWattsLabel_2            matlab.ui.control.Label
        YPFRadiantPowerLabel           matlab.ui.control.Label
        YPFRadiant                     matlab.ui.control.NumericEditField
        ChromaticityEditFieldLabel     matlab.ui.control.Label
        Chromaticity                   matlab.ui.control.EditField
        PPFQuantaEffLabel              matlab.ui.control.Label
        PPFQuantaWatt                  matlab.ui.control.NumericEditField
        MicromolessecWLabel            matlab.ui.control.Label
        YPFRadiantEffLabel             matlab.ui.control.Label
        YPFRadiantWatt                 matlab.ui.control.NumericEditField
        RadiantWattsWLabel_2           matlab.ui.control.Label
        LumensLabel                    matlab.ui.control.Label
        LightOutputLabel               matlab.ui.control.Label
        LuxEdit                        matlab.ui.control.NumericEditField
        LightOutputEffEditFieldLabel   matlab.ui.control.Label
        LightOutputWatt                matlab.ui.control.NumericEditField
        LumensWLabel                   matlab.ui.control.Label
        RadiantWattsLabel              matlab.ui.control.Label
        PPFRadiantPowerLabel           matlab.ui.control.Label
        PPFRadiant                     matlab.ui.control.NumericEditField
        RadiantWattsWLabel             matlab.ui.control.Label
        PPFRadiantEffLabel             matlab.ui.control.Label
        PPFRadiantWatt                 matlab.ui.control.NumericEditField
        MicromolessecLabel_2           matlab.ui.control.Label
        YPFQuantaEditFieldLabel        matlab.ui.control.Label
        YPFQuanta                      matlab.ui.control.NumericEditField
        MicromolessecWLabel_2          matlab.ui.control.Label
        YPFQuantaEffLabel              matlab.ui.control.Label
        YPFQuantaWatt                  matlab.ui.control.NumericEditField
        AddToGroupTableButton          matlab.ui.control.Button
        MultiplierLable                matlab.ui.control.Label
        SpectrumFilePathLabel          matlab.ui.control.Label
        Filepath                       matlab.ui.control.EditField
        WattageEditFieldLabel          matlab.ui.control.Label
        Wattage                        matlab.ui.control.NumericEditField
        GroupFileTab                   matlab.ui.container.Tab
        DataTable                      matlab.ui.control.Table
        SaveLocationEditFieldLabel     matlab.ui.control.Label
        SaveLocation                   matlab.ui.control.EditField
        SaveAsButton                   matlab.ui.control.Button
        AddDataButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        spectrum % Description
        McCreeFromGraph = load('McCree1972.txt');
        h = 6.63e-34; % Joule seconds, Planck's constant
        c = 3.00e8; % meter/second
        Avo = 6.02e23; % Avogardo constant
        LastDir
        PPF
        PPFperW
        PPF_mole
        PPF_moleperW
        YPF
        YPFperW
        YPF_mole
        YPF_moleperW
        Lux
        LuxperW
        xChrom
        yChrom
        Tc
    end
    
    methods (Access = public)
        
        function results = updatePlot(app)
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            max_specFlux = max(specFlux);
            app.MultiplierLable.Text = ['Multiplier = ',num2str(max_specFlux,'%10.4e\n'),' (W/nm)'];
            normalSpecFlux = specFlux/max_specFlux;
            plot(app.spectrumAxes,wave,normalSpecFlux);
            xlabel(app.spectrumAxes,'Wavelength (nm)');
            ylabel(app.spectrumAxes,'Normalized Spectral Flux');
            
            updateMeasurments(app);
        end
        
        
        function results = updateMeasurments(app)
            %% Calculate Measurments
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            watt = app.Wattage.Value;
            McCreePhoton = interp1(app.McCreeFromGraph(:,1),app.McCreeFromGraph(:,2),wave,'linear',0.0);
            McCreeWatt = McCreePhoton.*((wave*1e-9)/(app.h*app.c*app.Avo)); % convert growth/photon to growth/joule
            McCreeWatt = McCreeWatt/max(McCreeWatt);
            q1 = find(wave>=400,1,'first');
            q2 = find(wave<=700,1,'last');
            app.PPF = trapz(wave(q1:q2),specFlux(q1:q2)); % Watts
            app.PPFperW = app.PPF/watt;
            app.PPF_mole = 1e6*trapz(wave(q1:q2),specFlux(q1:q2).*(wave(q1:q2)*1e-9))/(app.h*app.c*app.Avo); % micromoles/s
            app.PPF_moleperW = app.PPF_mole/watt;
            app.YPF = trapz(wave,specFlux.*McCreeWatt); % Watts
            app.YPFperW = app.YPF/watt;
            app.YPF_mole = 1e6*trapz(wave,McCreePhoton.*specFlux.*(wave*1e-9))/(app.h*app.c*app.Avo); % micromoles/s
            app.YPF_moleperW = app.YPF_mole/watt;
            [app.Lux,app.xChrom,app.yChrom] = Lxy23Sep05([wave,specFlux]);
            app.Tc = CCT23Sep05(app.spectrum);
            app.LuxperW = app.Lux/watt;
            
            %% Show Measurments
            app.PPFRadiant.Value = app.PPF;
            app.PPFQuanta.Value = app.PPF_mole;
            app.YPFRadiant.Value = app.YPF;
            app.YPFQuanta.Value = app.YPF_mole;
            app.PPFRadiantWatt.Value = app.PPFperW;
            app.PPFQuantaWatt.Value = app.PPF_moleperW;
            app.YPFRadiantWatt.Value = app.YPFperW;
            app.YPFQuantaWatt.Value = app.YPF_moleperW;
            app.LuxEdit.Value = app.Lux;
            app.LightOutputWatt.Value = app.LuxperW;
            app.Chromaticity.Value = ['(',num2str(app.xChrom,3),',',num2str(app.yChrom,3),')'];
            
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function BrowseButtonPushed(app, event)
            if isempty(app.LastDir)
                [FileName,PathName,FilterIndex] = uigetfile({'*.*'},'Select Spectrum');
            else
                [FileName,PathName,FilterIndex] = uigetfile({[app.LastDir,'*.*']},'Select Spectrum');
            end
            if FilterIndex == 1
                app.Filepath.Value = fullfile(PathName,FileName);
                app.spectrum = load(fullfile(PathName,FileName));
                app.LastDir = PathName;
                updatePlot(app);
            end
        end

        % Callback function
        function FilepathValueChanged(app, event)
            value = app.Filepath.Value;
            if 2 == exist(value,'file')
                app.spectrum = load(value);
                updatePlot(app);
            end
        end

        % Value changed function: Wattage
        function WattageValueChanged(app, event)
            value = app.Wattage.Value;
            updateMeasurments(app);
        end

        % Cell selection callback: DataTable
        function DataTableCellSelection(app, event)
            
        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
            if isempty(app.LastDir)
                [FileName,PathName,FilterSpec] = uigetfile({'*.*'},'Select Spectrums', 'MultiSelect', 'on');
            else
                [FileName,PathName,FilterSpec] = uigetfile({[app.LastDir,'*.*']},'Select Spectrum', 'MultiSelect', 'on');
            end
            if FilterSpec ~= 0
                if ischar(FileName)
                    FileName = {FileName};
                end
                for i = 1:length(FileName)
                    app.Filepath.Value = fullfile(PathName,FileName{i});
                    app.spectrum = load(fullfile(PathName,FileName{i}));
                    app.LastDir = PathName;
                    prompt = {'File Name:','Enter Spectrum Wattage:'};
                    dlg_title = 'Wattage';
                    num_lines = 1;
                    defaultans = {fullfile(PathName,FileName{i}),'20'};
                    wattage = inputdlg(prompt,dlg_title,num_lines,defaultans);
                    updatePlot(app);
                    app.Wattage.Value = str2num(wattage{2});
                    tableData = app.DataTable.Data;
                    if size(tableData) == [0,0]
                        newLine = {'Units','Watts',...
                            'Radiant Watts','Radiant Watts/Watt','Micromoles/sec','Micromoles/sec/Watt',...
                            'Radiant Watts','Radiant Watts/Watt','Micromoles/sec','Micromoles/sec/Watt',...
                            '(x,y)'};
                        app.DataTable.Data = newLine;
                        newLine = {app.Filepath.Value,app.Wattage.Value,...
                            app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                            app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                            app.Lux,app.LuxperW,app.Chromaticity.Value};
                        app.DataTable.Data =[app.DataTable.Data; newLine];
                    else
                        newLine = {app.Filepath.Value,app.Wattage.Value,...
                            app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                            app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                            app.Lux,app.LuxperW,app.Chromaticity.Value};
                        app.DataTable.Data =[app.DataTable.Data; newLine];
                    end
                end
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            [FileName,PathName,FilterIndex] = uiputfile({'*.xls, *.xlsx','Excel File (*.xls, *.xlsx)';...
                '*.csv','Comma Delimincated File (*.csv)';...
                '*.txt','Tab Delimincated File (*.txt)';...
                '*.m','MATLAB Data File (*.mat)'},...
                'Save Data File','Plant Data Files.xls');
            fullFileName = fullfile(PathName, FileName);
            dataTable = cell2table(app.DataTable.Data,'VariableNames',app.DataTable.ColumnName);
            switch FilterIndex
                case 0
                    app.SaveLocation.Value = 'User Clicked Cancel';
                case 1
                    app.SaveLocation.Value = fullFileName;
                    writetable(dataTable,fullFileName);
                case 2
                    app.SaveLocation.Value = fullFileName;
                    writetable(dataTable,fullFileName,'Delimiter',',','QuoteStrings',true);
                case 3
                    app.SaveLocation.Value = fullFileName;
                    writetable(dataTable,fullFileName,'Delimiter','\t');
                case 4
                    app.SaveLocation.Value = fullFileName;
                    save(fullFileName,'dataTable');
                otherwise
                    app.SaveLocation.Value = 'There was an Error. Please try again.';
            end
        end

        % Button pushed function: AddToGroupTableButton
        function AddToGroupTableButtonPushed(app, event)
            tableData = app.DataTable.Data;
            if size(tableData) == [0,0]
                newLine = {'Units','Watts',...
                    'Radiant Watts','Radiant Watts/Watt','Micromoles/sec','Micromoles/sec/Watt',...
                    'Radiant Watts','Radiant Watts/Watt','Micromoles/sec','Micromoles/sec/Watt',...
                    'Lux','Lux/Watt','(x,y)'};
                app.DataTable.Data = newLine;
                newLine = {app.Filepath.Value,app.Wattage.Value,...
                    app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                    app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                    app.Lux,app.LuxperW,app.Chromaticity.Value};
                app.DataTable.Data =[app.DataTable.Data; newLine];
            else
                newLine = {app.Filepath.Value,app.Wattage.Value,...
                    app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                    app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                    app.Lux,app.LuxperW,app.Chromaticity.Value};
                app.DataTable.Data =[app.DataTable.Data; newLine];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlantMetricCalculatorUIFigure = uifigure;
            app.PlantMetricCalculatorUIFigure.Name = 'Plant Metric Calculator';
            setAutoResize(app, app.PlantMetricCalculatorUIFigure, true)
            app.TabGroup = uitabgroup(app.PlantMetricCalculatorUIFigure);
            app.SingleFileTab = uitab(app.TabGroup);
            app.SingleFileTab.Title = 'Single File';
            app.SingleFileTab.Units = 'pixels';
            app.BrowseButton = uibutton(app.SingleFileTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.spectrumAxes = uiaxes(app.SingleFileTab);
            title(app.spectrumAxes, 'Spectrum');
            xlabel(app.spectrumAxes, 'Wavelength (nm)');
            ylabel(app.spectrumAxes, 'Power');
            app.MeasurementsPanel = uipanel(app.SingleFileTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.MicromolessecLabel = uilabel(app.MeasurementsPanel);
            app.MicromolessecLabel.Text = 'Micromoles/sec';
            app.PPFQuantaEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.PPFQuantaEditFieldLabel.Text = 'PPF Quanta';
            app.PPFQuanta = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFQuanta.ValueDisplayFormat = '%5.3f';
            app.PPFQuanta.Editable = 'off';
            app.RadiantWattsLabel_2 = uilabel(app.MeasurementsPanel);
            app.RadiantWattsLabel_2.Text = 'Radiant Watts';
            app.YPFRadiantPowerLabel = uilabel(app.MeasurementsPanel);
            app.YPFRadiantPowerLabel.Text = 'YPF Radiant Power';
            app.YPFRadiant = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFRadiant.ValueDisplayFormat = '%5.3f';
            app.YPFRadiant.Editable = 'off';
            app.ChromaticityEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.ChromaticityEditFieldLabel.Text = 'Chromaticity';
            app.Chromaticity = uieditfield(app.MeasurementsPanel, 'text');
            app.Chromaticity.Editable = 'off';
            app.PPFQuantaEffLabel = uilabel(app.MeasurementsPanel);
            app.PPFQuantaEffLabel.Text = 'PPF Quanta Eff.';
            app.PPFQuantaWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFQuantaWatt.ValueDisplayFormat = '%5.3f';
            app.PPFQuantaWatt.Editable = 'off';
            app.MicromolessecWLabel = uilabel(app.MeasurementsPanel);
            app.MicromolessecWLabel.Text = 'Micromoles/sec/W';
            app.YPFRadiantEffLabel = uilabel(app.MeasurementsPanel);
            app.YPFRadiantEffLabel.Text = 'YPF Radiant Eff.';
            app.YPFRadiantWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFRadiantWatt.ValueDisplayFormat = '%5.3f';
            app.YPFRadiantWatt.Editable = 'off';
            app.RadiantWattsWLabel_2 = uilabel(app.MeasurementsPanel);
            app.RadiantWattsWLabel_2.Text = 'Radiant Watts/W';
            app.LumensLabel = uilabel(app.MeasurementsPanel);
            app.LumensLabel.Text = 'Lumens';
            app.LightOutputLabel = uilabel(app.MeasurementsPanel);
            app.LightOutputLabel.Text = 'Light Output';
            app.LuxEdit = uieditfield(app.MeasurementsPanel, 'numeric');
            app.LuxEdit.ValueDisplayFormat = '%5.2f';
            app.LuxEdit.Editable = 'off';
            app.LightOutputEffEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.LightOutputEffEditFieldLabel.Text = 'Light Output Eff.';
            app.LightOutputWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.LightOutputWatt.ValueDisplayFormat = '%5.3f';
            app.LumensWLabel = uilabel(app.MeasurementsPanel);
            app.LumensWLabel.Text = 'Lumens/W';
            app.RadiantWattsLabel = uilabel(app.MeasurementsPanel);
            app.RadiantWattsLabel.Text = 'Radiant Watts';
            app.PPFRadiantPowerLabel = uilabel(app.MeasurementsPanel);
            app.PPFRadiantPowerLabel.Text = 'PPF Radiant Power';
            app.PPFRadiant = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFRadiant.ValueDisplayFormat = '%5.3f';
            app.PPFRadiant.Editable = 'off';
            app.RadiantWattsWLabel = uilabel(app.MeasurementsPanel);
            app.RadiantWattsWLabel.Text = 'Radiant Watts/W';
            app.PPFRadiantEffLabel = uilabel(app.MeasurementsPanel);
            app.PPFRadiantEffLabel.Text = 'PPF Radiant Eff.';
            app.PPFRadiantWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFRadiantWatt.ValueDisplayFormat = '%5.3f';
            app.PPFRadiantWatt.Editable = 'off';
            app.MicromolessecLabel_2 = uilabel(app.MeasurementsPanel);
            app.MicromolessecLabel_2.Text = 'Micromoles/sec';
            app.YPFQuantaEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.YPFQuantaEditFieldLabel.Text = 'YPF Quanta';
            app.YPFQuanta = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFQuanta.ValueDisplayFormat = '%5.3f';
            app.YPFQuanta.Editable = 'off';
            app.MicromolessecWLabel_2 = uilabel(app.MeasurementsPanel);
            app.MicromolessecWLabel_2.Text = 'Micromoles/sec/W';
            app.YPFQuantaEffLabel = uilabel(app.MeasurementsPanel);
            app.YPFQuantaEffLabel.Text = 'YPF Quanta Eff.';
            app.YPFQuantaWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFQuantaWatt.ValueDisplayFormat = '%5.3f';
            app.YPFQuantaWatt.Editable = 'off';
            app.AddToGroupTableButton = uibutton(app.MeasurementsPanel, 'push');
            app.AddToGroupTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddToGroupTableButtonPushed, true);
            app.AddToGroupTableButton.Text = '  Add To Group Table  ';
            app.MultiplierLable = uilabel(app.SingleFileTab);
            app.MultiplierLable.Text = 'Multiplier = N/A';
            app.SpectrumFilePathLabel = uilabel(app.SingleFileTab);
            app.SpectrumFilePathLabel.Text = 'Spectrum File Path';
            app.Filepath = uieditfield(app.SingleFileTab, 'text');
            app.Filepath.ValueChangedFcn = createCallbackFcn(app, @FilepathValueChanged, true);
            app.Filepath.Value = 'File Name...';
            app.WattageEditFieldLabel = uilabel(app.SingleFileTab);
            app.WattageEditFieldLabel.Text = 'Wattage';
            app.Wattage = uieditfield(app.SingleFileTab, 'numeric');
            app.Wattage.LowerLimitInclusive = 'off';
            app.Wattage.ValueChangedFcn = createCallbackFcn(app, @WattageValueChanged, true);
            app.Wattage.Limits = [0 Inf];
            app.Wattage.Value = 1;
            app.GroupFileTab = uitab(app.TabGroup);
            app.GroupFileTab.Title = 'Group File';
            app.GroupFileTab.Units = 'pixels';
            app.DataTable = uitable(app.GroupFileTab);
            app.DataTable.ColumnName = {'Spectrum_File'; 'Wattage'; 'PPF_R'; 'PPF__RperW'; 'PPF_Q'; 'PPF_QperW'; 'YPF_R'; 'YPF_RperW'; 'YPF_Q'; 'YPF_QperW'; 'Lux'; 'Lux_perW'; 'Chromaticity'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnEditable = [true true false false false false false false false false false false false];
            app.DataTable.CellSelectionCallback = createCallbackFcn(app, @DataTableCellSelection, true);
            app.SaveLocationEditFieldLabel = uilabel(app.GroupFileTab);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocation = uieditfield(app.GroupFileTab, 'text');
            app.SaveLocation.Value = 'Click Save As...';
            app.SaveAsButton = uibutton(app.GroupFileTab, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As...';
            app.AddDataButton = uibutton(app.GroupFileTab, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlantCalculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlantMetricCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlantMetricCalculatorUIFigure)
        end
    end
end